classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('c2vsimDiversionData.mat', 'DivSpec');
            load('c2vsimGeometryData.mat', 'c2vsim_riverNodes')
            load('c2vsimGeometryData.mat', 'c2vsim_mesh')
            load('c2vsimDiversionData.mat', 'Diversions');
            riv_div = [DivSpec.IRDV]';
            riv_div = unique(riv_div);
            riv_div(riv_div==0,:) =[];
            app.Data.DivPoints.X = [c2vsim_riverNodes(riv_div,1).X]';
            app.Data.DivPoints.Y = [c2vsim_riverNodes(riv_div,1).Y]';
            app.Data.DivPoints.IRDV = riv_div;
            app.Data.DivSpec = DivSpec;
            app.Data.Mesh = c2vsim_mesh;
            app.Data.DivTimeSeries = Diversions;
            
            plot(app.UIAxes, app.Data.DivPoints.X, app.Data.DivPoints.Y, 'ob',"MarkerSize",4,'MarkerFaceColor', 'b');
            axis(app.UIAxes,'off')
            app.UIFigure.Position = [100 100 2*820 2*480];
            app.UIAxes.Position = [1 1 2*324 2*480];
            app.UIAxes2.Position = [2*324 2*75 2*450 2*258];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            p = app.UIAxes.CurrentPoint;
            [c, d] = min(sqrt((app.Data.DivPoints.X - p(1,1)).^2 + (app.Data.DivPoints.Y - p(1,2)).^2));
            hold(app.UIAxes, 'off');
            hold(app.UIAxes2, 'off');
            elem_id = [];
            if c < 2000
                cla(app.UIAxes);
                cla(app.UIAxes2);
                %hold(app.UIAxes, 'off');
                id = find([app.Data.DivSpec.IRDV]' == app.Data.DivPoints.IRDV(d));
                for ii = 1:length(id)
                    elem_id = [elem_id; app.Data.DivSpec(id(ii)).IERELS];
                    plot(app.UIAxes2, cumsum([0;app.Data.DivTimeSeries.Data(:, app.Data.DivSpec(id(ii)).ICOLRL)])) ;
                    hold(app.UIAxes2, 'on');
                end
                
                for ii = 1: length(elem_id)
                    if ii == 1
                        hold(app.UIAxes, 'on');
                    end
                    if elem_id(ii) == 0
                        continue;
                    end
                    patch(app.UIAxes, app.Data.Mesh(elem_id(ii),1).X, app.Data.Mesh(elem_id(ii),1).Y, 'r');
                    
                end
                plot(app.UIAxes, app.Data.DivPoints.X, app.Data.DivPoints.Y, 'ob',"MarkerSize",4,'MarkerFaceColor', 'b');
                hold(app.UIAxes, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end